classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimulationMenu             matlab.ui.container.Menu
        NewsimulationMenu          matlab.ui.container.Menu
        SavesimulationMenu         matlab.ui.container.Menu
        LoadsimulationMenu         matlab.ui.container.Menu
        RequestsMenu_2             matlab.ui.container.Menu
        ListMenu                   matlab.ui.container.Menu
        LoadMenu                   matlab.ui.container.Menu
        VisualizeMenu              matlab.ui.container.Menu
        DepotsMenu                 matlab.ui.container.Menu
        ListMenu_2                 matlab.ui.container.Menu
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout4                matlab.ui.container.GridLayout
        GridLayout6                matlab.ui.container.GridLayout
        ShowButton_2               matlab.ui.control.Button
        Label                      matlab.ui.control.Label
        GridLayout5                matlab.ui.container.GridLayout
        ShowButton                 matlab.ui.control.Button
        RequestsLabel              matlab.ui.control.Label
        TableVehicle               matlab.ui.control.Table
        TableRequest               matlab.ui.control.Table
        Image                      matlab.ui.control.Image
        GridLayout3                matlab.ui.container.GridLayout
        StatusLabel                matlab.ui.control.Label
        SimulateButton             matlab.ui.control.Button
        SolverPanel                matlab.ui.container.Panel
        DropDown                   matlab.ui.control.DropDown
        NewvehiclePanel            matlab.ui.container.Panel
        CapacitySpinner            matlab.ui.control.Spinner
        CapacitySpinnerLabel       matlab.ui.control.Label
        AddDepotButton             matlab.ui.control.Button
        NewrequestPanel            matlab.ui.container.Panel
        DropoffendEditField        matlab.ui.control.EditField
        DropoffendEditFieldLabel   matlab.ui.control.Label
        PickupstartEditField       matlab.ui.control.EditField
        PickupstartEditFieldLabel  matlab.ui.control.Label
        AddButton                  matlab.ui.control.Button
        MapsidelengthPanel         matlab.ui.container.Panel
        Spinner                    matlab.ui.control.Spinner
    end

    
    properties (Access = private)        
        uiState; %State of the UI. 1 - basic, 2 - add request, 3 - add depot
        
        graph; 
        coordsNodesImg;
        coordsArcsImg;
        
        img; % an underlying layer under the top layers
        imgTop; % reference to the image that is displayed in the main Image component
        resolution; % global parameter
        padding; % global parameter
        nodeRadius; % radius of all the nodes in the images
        
        pickUpStartEdited;
        dropOffEndEdited;
          
        requestsExist; % indicates that the simulation contains requests
        pickupAdded; % indicate that the request already has a pickup location
        requests; % array of requests
        
        depotsExist; % indicates that the simulation contains depots
        depots; % array of depots
        
        vehiclesExist; % indicates that the simulation contains vehicles
        vehicles; % array of vehicles
        
        solver;
    end
    
    methods (Access = private)           
        function updateImg(app)
            %1. Create emptyn white image
            app.img = uint8(ones(app.resolution,app.resolution,3)*255);
            
            %2. Draw all lines
            for i = 1:app.graph.nArcs
                app.img = drawLine(app.img, ...
                                  app.coordsArcsImg(i,1), ...
                                  app.coordsArcsImg(i,2), ...
                                  app.coordsArcsImg(i,3), ...
                                  app.coordsArcsImg(i,4), ...
                                  [0,0,0], ...
                                  3);
            end
            
            %3. Draw all nodes
            for i = 1:app.graph.nNodes
                app.img = drawDot(app.img, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,0,0], ...
                                  -1);
            end
            
            %Update the image
            app.Image.ImageSource = app.img;
        end
        
        function updateImgTopLayer(app, newPickupNodeId)
            % Add a top layer to the img with the request and depot nodes
            % params:
            %   - newPickup: the nodeID of the pickup node of the new
            %   request
            
            [pickUps, dropOffs, depotsLocal] = getRequestDepotnodes(app);
            
            %Check if the newPickupNodeId parameter is valid
            if(newPickupNodeId > 0 && newPickupNodeId <= app.graph.nNodes)
                disp("Valid pickup point")
                pickUps = [pickUps; newPickupNodeId];
            end
            
            % Copy the base img to the top layer
            app.imgTop = app.img;
            
            for i = 1:app.graph.nNodes
                % Draw depots       
                if(ismember(i, depotsLocal))
                    app.imgTop =  drawDepotRect(app.imgTop, ...
                        app.coordsNodesImg(i,1)-app.nodeRadius, ...
                        app.coordsNodesImg(i,2)-app.nodeRadius, ...
                        app.nodeRadius, app.nodeRadius, [0,0,0]);
                end
                
                % Draw nodes that are both pickup and dropoff places
                % half green - half red
                if(ismember(i, pickUps) && ismember(i,dropOffs))
                    app.imgTop = drawDot(app.imgTop, ...
                                  app.coordsNodesImg(i,1), ...
                                  app.coordsNodesImg(i,2), ...
                                  app.nodeRadius, ...
                                  [0,0,0], ...
                                  1, ...
                                  [0,128,0], ...
                                  [255,0,0]);
                else
                    % Draw pickups - green
                    if(ismember(i, pickUps))
                        app.imgTop = drawDot(app.imgTop, ...
                                      app.coordsNodesImg(i,1), ...
                                      app.coordsNodesImg(i,2), ...
                                      app.nodeRadius, ...
                                      [0,0,0], ...
                                      1, ...
                                      [0,128,0], ...
                                      -1);
                    end
                    
                    % Draw dropoffs - red
                    if(ismember(i,dropOffs))
                        app.imgTop = drawDot(app.imgTop, ...
                                      app.coordsNodesImg(i,1), ...
                                      app.coordsNodesImg(i,2), ...
                                      app.nodeRadius, ...
                                      [0,0,0], ...
                                      1, ...
                                      [255,0,0], ...
                                      -1);
                    end
                end
            end
            
            %Update the image
            app.Image.ImageSource = app.imgTop;
        end
        
        function convertToImgCoords(app)
            max_x = max(app.graph.nodeCoords(:,1));
            max_y = max(app.graph.nodeCoords(:,1));
            
            % The padding
            offset = app.padding;
            % Scaling value along the x axis
            multip_x = (app.resolution - 2 * app.padding)/max_x;
            % Scaling value along the y axis
            multip_y = (app.resolution - 2 * app.padding)/max_y;
            
            %Coordinates of nodes
            app.coordsNodesImg = zeros(app.graph.nNodes,2);
            for i = 1:app.graph.nNodes
                app.coordsNodesImg(i,1) = round(offset + multip_x * app.graph.nodeCoords(i,1));
                app.coordsNodesImg(i,2) = round(offset + multip_y * app.graph.nodeCoords(i,2));
            end
            
            % Init the global radius parameter
            app.nodeRadius = floor((multip_x+multip_y)/20);            
            if(app.nodeRadius > app.padding)
               app.nodeRadius = app.padding - 1;
            end
            
            %Start and end coordinates of arcs
            app.coordsArcsImg = zeros(app.graph.nNodes,4);
            for i = 1:app.graph.nArcs
                app.coordsArcsImg(i,1) = round(offset + multip_x * app.graph.arcCoords(i,1));
                app.coordsArcsImg(i,2) = round(offset + multip_y * app.graph.arcCoords(i,2));
                app.coordsArcsImg(i,3) = round(offset + multip_x * app.graph.arcCoords(i,3));
                app.coordsArcsImg(i,4) = round(offset + multip_y * app.graph.arcCoords(i,4));
            end
        end
        
        function handleImgClick(app, coordX, coordY)
            %Declare local persistent variable
            persistent newRequest
            
            switch(app.uiState)
                case 1 % STATE_BASIC
                    
                case 2 % STATE_ADD_REQUEST_PICKUP
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    %Check if nodeId is valid
                    if(nodeId ~= -1)
                        fprintf("Pickup node is %d\n",nodeId)
                        % Create an empty request
                        newRequest = darpRequest;
                        %Add pickup node
                        newRequest.pickUpNodeId = nodeId;
                        if(~app.requestsExist)
                            newRequest.requestId = 1;
                        else
                            newRequest.requestId = size(app.requests,1);
                        end
                        % Update the top layer of the img
                        app.updateImgTopLayer(nodeId)
                        % Switch to STATE_ADD_REQUEST_DROPOFF
                        app.uiState = 3;
                        app.StatusLabel.Text = "Select dropoff node!";
                    end

                case 3 % STATE_ADD_REQUEST_DROPOFF
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    if(nodeId ~= -1)
                        fprintf("Dropoff node is %d\n",nodeId)
                        % Add dropoff nodeid
                        newRequest.dropOffNodeId = nodeId;
                        % Get pickup and dropoff times
                        newRequest.pickUpTimeEarliest = convertTimeToMin(app.PickupstartEditField.Value);
                        newRequest.dropOffTimeLatest = convertTimeToMin(app.DropoffendEditField.Value);
                        % Add request to all stored requests
                        if(~app.requestsExist)
                            app.requests = newRequest;
                            app.requestsExist = true;
                        else
                            app.requests = [app.requests; newRequest];
                        end
                        % Update the top layer of the img
                        app.updateImgTopLayer(-1)
                        % Switch to STATE_BASIC
                        app.uiState = 1;
                        app.StatusLabel.Text = "App is ready.";
                        app.NewrequestPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.NewrequestPanel.FontWeight = 'normal';
                        app.AddButton.Text = "Add new";
                        % Enable the editfields
                        app.PickupstartEditField.Editable = "on";
                        app.DropoffendEditField.Editable = "on";
                        % Update the table with the requests
                        app.updateRequestsTable();
                    end
                case 4 % STATE_ADD_DEPOT
                    nodeId = app.findNodeByCoord(coordX, coordY);
                    if(nodeId ~= -1)
                        newVehicle = darpVehicle;
                        newVehicle.depotNodeId = nodeId;
                        if(~app.vehiclesExist)
                            newVehicle.vehicleId = 1;
                            app.vehicles = newVehicle;
                            app.vehiclesExist = true;
                        else
                            newVehicle.requestId = size(app.vehicles,1);
                            app.vehicles = [app.vehicles; newVehicle];
                        end
                    end
                    % Update the top layer of the img
                    app.updateImgTopLayer(-1)
                    % Switch to STATE_BASIC
                    app.uiState = 1;
                    app.StatusLabel.Text = "App is ready.";
                    app.NewvehiclePanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                    app.NewvehiclePanel.FontWeight = 'normal';
                    app.AddButton.Text = "Add new";
                    % Enable the editfields
                    app.PickupstartEditField.Editable = "on";
                    app.DropoffendEditField.Editable = "on";
                    % Update the table with the requests
                    app.updateRequestsTable();
           end
        end
        
        function id = findNodeByCoord(app, coordXIn, coordYIn)
            % Returns the id of the node based on the coordinates
            id = -1;
            for i = 1:app.graph.nNodes
                if(app.checkClickInNode(i,coordXIn, coordYIn))
                    id = i;
                end
            end
        end
        
        function isIn = checkClickInNode(app, nodeId, coordXIn, coordYIn)
            xNodeImg = app.coordsNodesImg(nodeId, 1);
            yNodeImg = app.coordsNodesImg(nodeId, 2);
            if((xNodeImg - coordXIn)^2 + (yNodeImg - coordYIn)^2 <= app.nodeRadius^2)
                isIn = true;
            else
                isIn = false;
            end
        end
    
        function [pickUps, dropOffs, depots] = getRequestDepotnodes(app)
            % Iterate over the array of requests and depots. Returns the
            % nodeIds of pickup, dropoff and depot nodes           
            
            if(app.requestsExist)
                pickUpsTemp = zeros(size(app.requests,1),1);
                dropOffsTemp = zeros(size(app.requests,1),1);
                % Iterate over all requests
                for i = 1:size(app.requests,1)
                    pickUpsTemp(i) = app.requests(i).pickUpNodeId;
                    dropOffsTemp(i) = app.requests(i).dropOffNodeId;
                end
                pickUps = pickUpsTemp;
                dropOffs = dropOffsTemp;
            else
                pickUps = [0];
                dropOffs = [0];
            end
            
            if(app.vehiclesExist)
                depotsTemp = zeros(size(app.vehicles,1),1);
                %Iterate over all vehicles
                for i = 1:size(app.depots,1)
                    depotsTemp(i) = app.vehicles(i).depotNodeId;
                end
                depots = unique(depotsTemp);
            else
                depots = [0];
            end
        end
    
        function updateRequestsTable(app)
            ids = zeros(size(app.requests,1),1);
            pickUpNodes = zeros(size(app.requests,1),1);
            pickUpTimes = string(zeros(size(app.requests,1),1));
            dropOffNodes = zeros(size(app.requests,1),1);
            dropOffTimes = string(zeros(size(app.requests,1),1));
            shows = zeros(size(app.requests,1),1);
            
            for i = 1:size(app.requests)
                thisRequest = app.requests(i);
                %Collect the data of the request
                ids(i) = thisRequest.requestId;
                pickUpNodes(i) = thisRequest.pickUpNodeId;
                pickUpTimes(i) = convertMinToTime(thisRequest.pickUpTimeEarliest);
                dropOffNodes(i) = thisRequest.dropOffNodeId;
                dropOffTimes(i) = convertMinToTime(thisRequest.dropOffTimeLatest);
                shows(i) = false;
            end
            dataTable = table(ids, pickUpNodes, pickUpTimes, dropOffNodes, dropOffTimes, shows);
            app.TableRequest.Data = dataTable;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Instantiate a darpGraph with the given sideLength       
            app.graph = darpSquareGridGraph(app.Spinner.Value);
            % Init the image properties
            app.resolution = 2048;
            app.padding = 50;
            %Calculate the image coordinates
            app.convertToImgCoords();
            
            % Initialize the status flags
            app.requestsExist = false;
            app.depotsExist = false;
            app.vehiclesExist = false;
            app.pickUpStartEdited = false;
            app.dropOffEndEdited = false;
            
            %Update the requests table headers
            app.TableRequest.ColumnName = {sprintf('Id'); sprintf('Pick-up\nnode'); sprintf('Pick-up\ntime'); sprintf('Drop-off\nnode'); sprintf('Drop-off\ntime'); sprintf('Show')};
            app.TableRequest.ColumnFormat = {'numeric','numeric','char','numeric','char', 'logical'};
      
            %Update the main image
            app.updateImg();
            
            %Set UI state
            app.uiState = 1;
            app.StatusLabel.Text = "App is ready.";
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            if(app.requestsExist || app.depotsExist)
                question = "Requests or depots have been added to this simulation. By changing the sidelength all requests and depots will be deleted. \n Do you want to continue?";
                answer = questdlg(question, "Warning", "Yes", "Cancel", "Yes");
                if(answer == "Yes")
                    % Delete the requests
                    app.requests = 0;
                    app.requestsExist = false;
                    % Delete the depots
                    app.depots = 0;
                    app.depotsExist = false;
                    %Update the graph
                    app.graph = darpSquareGridGraph(app.Spinner.Value);
                    %Update the image coordinates
                    app.convertToImgCoords();
                    %Update the main image
                    app.updateImg();
                else
                    % Reset the spinner value
                    app.Spinner.Value = app.graph.sideLength;
                end
            else
                %Update the graph
                app.graph = darpSquareGridGraph(app.Spinner.Value);
                %Update the image coordinates
                app.convertToImgCoords();
                %Update the main image
                app.updateImg(); 
            end
        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
           % Get the position of the image
            imgX = app.Image.Position(1);
            imgY = app.Image.Position(2);
            imgWidth = app.Image.Position(3);
            imgHeight = app.Image.Position(4);
  
            %The global coordinates of the click
            clickCoords = event.Source.CurrentPoint;
            
            % Handle rectangleness
            if(imgWidth > imgHeight)
                imgX = imgX + (imgWidth - imgHeight) / 2;
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgHeight && ...
                    clickCoords(2) < imgY + imgHeight)
                    
                    clickXImg = floor((imgY + imgHeight - clickCoords(2))/imgHeight*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgHeight*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg) 
                end
            else
                imgY = imgY + (imgHeight - imgWidth) / 2;  
                %Check if the click occured inside the image
                if(clickCoords(1) > imgX && clickCoords(2) > imgY && ...
                    clickCoords(1) < imgX + imgWidth && ...
                    clickCoords(2) < imgY + imgWidth)
                    
                    clickXImg = floor((imgY + imgWidth - clickCoords(2))/imgWidth*app.resolution);
                    clickYImg = floor((clickCoords(1) - imgX)/imgWidth*app.resolution);
                    
                    fprintf("Img click: X,Y = %d,%d\n", clickXImg, clickYImg)
                    app.handleImgClick(clickXImg, clickYImg)           
                end
            end
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if(app.uiState == 1)
                %If the text is incorrect
                if(validateTimeInput(app.PickupstartEditField.Value))
                    app.PickupstartEditField.Value = '00:00';
                    app.PickupstartEditField.FontColor = 'red';
                    app.pickUpStartEdited = false;
                    app.StatusLabel.Text = "Incorrect pickup time.";
                elseif(validateTimeInput(app.DropoffendEditField.Value))
                    app.DropoffendEditField.Value = '00:00';
                    app.DropoffendEditField.FontColor = 'red';
                    app.StatusLabel.Text = "Incorrect drop off time.";
                elseif(~app.pickUpStartEdited || ~app.dropOffEndEdited)
                    app.StatusLabel.Text = "Sure?";
                else
                    % Disable the editfields
                    app.PickupstartEditField.Editable = "off";
                    app.DropoffendEditField.Editable = "off";
                    % Modify appearance
                    app.NewrequestPanel.BackgroundColor = '#9BE2C5'; %lightgreen
                    app.NewrequestPanel.FontWeight = 'bold';
                    app.AddButton.Text = "Cancel";
                    % Switch to STATE_ADD_REQUEST
                    app.uiState = 2; 
                    app.StatusLabel.Text = "Select pickup node!";
                end
            elseif(app.uiState == 2 || app.uiState == 3)
                app.uiState = 1; % set STATE_BASE
                app.StatusLabel.Text = "App is ready.";
                app.NewrequestPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                app.NewrequestPanel.FontWeight = 'normal';
                app.AddButton.Text = "Add new";
                % Enable the editfields
                app.PickupstartEditField.Editable = "on";
                app.DropoffendEditField.Editable = "on";
                % Redraw the top layer to remove the possibly added pickup
                % node
                app.updateImgTopLayer(-1)
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            %Handle ESC button pressed when adding new requests or depots
            switch key
                case 'escape'
                    if(app.uiState == 2 || app.uiState == 3)
                        app.uiState = 1; % set STATE_ADD_REQUEST
                        app.NewrequestPanel.BackgroundColor = [0.94,0.94,0.94]; %orginal grey
                        app.NewrequestPanel.FontWeight = 'normal';
                        app.AddButton.Text = "Add new";
                        % Enable the editfields
                        app.PickupstartEditField.Editable = "on";
                        app.DropoffendEditField.Editable = "on";
                        % Redraw the top layer to remove the possibly added pickup
                        % node
                        app.updateImgTopLayer(-1)   
                    end
                otherwise
                    %Other key was pressed
            end
        end

        % Value changing function: PickupstartEditField
        function PickupstartEditFieldValueChanging(app, event)
            app.PickupstartEditField.FontColor = 'black';
            app.pickUpStartEdited = true;
        end

        % Value changing function: DropoffendEditField
        function DropoffendEditFieldValueChanging(app, event)
            app.DropoffendEditField.FontColor = 'black';
            app.dropOffEndEdited = true;
        end

        % Button pushed function: AddDepotButton
        function AddDepotButtonPushed(app, event)
            if(app.uiState == 1)
                % Disable the spinner
                app.CapacitySpinner.Editable = "off";
                % Modify appearance
                app.NewvehiclePanel.BackgroundColor = '#9BE2C5'; %lightgreen
                app.NewvehiclePanel.FontWeight = 'bold';
                app.AddDepotButton.Text = "Cancel";
                % Switch to STATE_ADD_DEPOT
                app.uiState = 4; 
                app.StatusLabel.Text = "Select pickup node!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.SimulationMenu = uimenu(app.UIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.NewsimulationMenu = uimenu(app.SimulationMenu);
            app.NewsimulationMenu.Text = 'New simulation';
            app.SavesimulationMenu = uimenu(app.SimulationMenu);
            app.SavesimulationMenu.Text = 'Save simulation';
            app.LoadsimulationMenu = uimenu(app.SimulationMenu);
            app.LoadsimulationMenu.Text = 'Load simulation';
            app.RequestsMenu_2 = uimenu(app.UIFigure);
            app.RequestsMenu_2.Text = 'Requests';
            app.ListMenu = uimenu(app.RequestsMenu_2);
            app.ListMenu.Text = 'List';
            app.LoadMenu = uimenu(app.RequestsMenu_2);
            app.LoadMenu.Text = 'Load';
            app.VisualizeMenu = uimenu(app.RequestsMenu_2);
            app.VisualizeMenu.Text = 'Visualize';
            app.DepotsMenu = uimenu(app.UIFigure);
            app.DepotsMenu.Text = 'Depots';
            app.ListMenu_2 = uimenu(app.DepotsMenu);
            app.ListMenu_2.Text = 'List';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.MapsidelengthPanel = uipanel(app.GridLayout3);
            app.MapsidelengthPanel.Title = 'Map side length';
            app.Spinner = uispinner(app.MapsidelengthPanel);
            app.Spinner.Limits = [3 10];
            app.Spinner.RoundFractionalValues = 'on';
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Value = 6;
            app.NewrequestPanel = uipanel(app.GridLayout3);
            app.NewrequestPanel.Title = 'New request';
            app.AddButton = uibutton(app.NewrequestPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.PickupstartEditFieldLabel = uilabel(app.NewrequestPanel);
            app.PickupstartEditFieldLabel.Text = 'Pickup start';
            app.PickupstartEditField = uieditfield(app.NewrequestPanel, 'text');
            app.PickupstartEditField.ValueChangingFcn = createCallbackFcn(app, @PickupstartEditFieldValueChanging, true);
            app.DropoffendEditFieldLabel = uilabel(app.NewrequestPanel);
            app.DropoffendEditFieldLabel.Text = 'Dropoff end';
            app.DropoffendEditField = uieditfield(app.NewrequestPanel, 'text');
            app.DropoffendEditField.ValueChangingFcn = createCallbackFcn(app, @DropoffendEditFieldValueChanging, true);
            app.NewvehiclePanel = uipanel(app.GridLayout3);
            app.NewvehiclePanel.Title = 'New vehicle';
            app.AddDepotButton = uibutton(app.NewvehiclePanel, 'push');
            app.AddDepotButton.ButtonPushedFcn = createCallbackFcn(app, @AddDepotButtonPushed, true);
            app.AddDepotButton.Text = 'Add';
            app.CapacitySpinnerLabel = uilabel(app.NewvehiclePanel);
            app.CapacitySpinnerLabel.Text = 'Capacity';
            app.CapacitySpinner = uispinner(app.NewvehiclePanel);
            app.CapacitySpinner.Limits = [1 10];
            app.CapacitySpinner.RoundFractionalValues = 'on';
            app.CapacitySpinner.Value = 3;
            app.SolverPanel = uipanel(app.GridLayout3);
            app.SolverPanel.Title = 'Solver';
            app.DropDown = uidropdown(app.SolverPanel);
            app.SimulateButton = uibutton(app.GridLayout3, 'push');
            app.SimulateButton.Text = 'Simulate';
            app.StatusLabel = uilabel(app.GridLayout3);
            app.StatusLabel.Text = 'Status';
            app.Image = uiimage(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.TableRequest = uitable(app.GridLayout4);
            app.TableRequest.ColumnName = {'Id'; 'Pick-up\nnode'; 'Pick-up\ntime'; 'Drop-off\nnode'; 'Drop-off\ntime'; 'S'};
            app.TableRequest.RowName = {};
            app.TableRequest.ColumnSortable = true;
            app.TableVehicle = uitable(app.GridLayout4);
            app.TableVehicle.ColumnName = {'Id'; 'Node'; 'Capacity'};
            app.TableVehicle.RowName = {};
            app.TableVehicle.ColumnSortable = true;
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.RequestsLabel = uilabel(app.GridLayout5);
            app.RequestsLabel.Text = 'Requests';
            app.ShowButton = uibutton(app.GridLayout5, 'push');
            app.ShowButton.Text = 'Show';
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.Label = uilabel(app.GridLayout6);
            app.ShowButton_2 = uibutton(app.GridLayout6, 'push');
            app.ShowButton_2.Text = 'Show';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end