classdef solverWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        GridLayout3         matlab.ui.container.GridLayout
        ShowsolutionButton  matlab.ui.control.Button
        SavesolutionButton  matlab.ui.control.Button
        GridLayout2         matlab.ui.container.GridLayout
        STARTButton         matlab.ui.control.Button
        DropDown            matlab.ui.control.DropDown
        TextArea            matlab.ui.control.TextArea
        SelectsolverLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        callingApp;
        myVisualizerWindow;
        
        %Simulation data
        graph;
        requests;
        vehicles;
        routes;
        edgeTime;
        
        %
        routes_exist;
    end
    
    methods (Access = private)
        
        function updateTextArea(app)
            text = "";
            if(size(app.routes,1)>0)
                for i = 1:size(app.routes,1)                  
                    line = sprintf('Route %d nodes:', app.routes{i,1});
                    nodes = app.routes{i,2};
                    for j = 1:size(nodes,1)
                        line = sprintf('%s %d',line, nodes(j));
                    end
                    text = sprintf('%s%s\n',text,line);
                end
                app.TextArea.Value = text;
            else
                app.TextArea.Value = 'No routes';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, graphIn, requestsIn, vehiclesIn, edgeTime)
            app.callingApp = mainapp;
            
            %Store simulation data
            app.graph = graphIn;
            app.requests = requestsIn;
            app.vehicles = vehiclesIn;
            app.edgeTime = edgeTime;
           
            %Status flags
            app.routes_exist = false;
            
            % Hide button
            app.ShowsolutionButton.Visible = false;
            app.SavesolutionButton.Visible = false;
                      
            %TODO Disable calling app
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if(app.DropDown.Value == "Test solution")
                app.routes = cell(size(app.vehicles,1),2);
                %
                n_nodes = 5;
                %                
                for i = 1:size(app.vehicles,1)
                    nodes = zeros(n_nodes,1);
                    node = app.vehicles(i).depotNodeId;
                    nodes(1) = node;
                    
                    % Generate random steps
                    for j = 2:n_nodes
                        %Neighbors are the nodes where the adjecency matrix
                        %is 1
                        neighbors = find(app.graph.adjMat(node,:) == 1);
                        
                        %Iterate over all the neighbors
                        for k = 1:size(neighbors,2)
                            if(~ismember(neighbors(k),nodes))
                                nodes(j) = neighbors(k);
                                node = neighbors(k);
                            end
                        end
                    end
                    app.routes(i,:) = {uint16(app.vehicles(i).vehicleId), nodes};
                end
                app.routes_exist = true;    
            elseif(app.DropDown.Value == "Naive solution")
                solver = naiveSolver(app.graph, app.requests, app.vehicles, app.edgeTime);
                solver.solve();
                app.routes = solver.getRoutes();
                app.routes_exist = true;
            end
            
            app.updateTextArea();
            app.ShowsolutionButton.Visible = true;
            app.SavesolutionButton.Visible = true;
        end

        % Button pushed function: ShowsolutionButton
        function ShowsolutionButtonPushed(app, event)
            if(app.routes_exist)               
                result = 2; %Changing to visualization mode
                %Returning data to the calling app
                app.callingApp.returnFromSolverSelect(result, app.routes)
                delete(app)
            else
                warndlg("Solution is not ready.")
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Main uistate should be configuration mode
            result = 1; 
            %Returning data to the calling app
            app.callingApp.returnFromSolverSelect(result, app.routes)
            %Delete this window
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectsolverLabel = uilabel(app.GridLayout);
            app.SelectsolverLabel.Text = 'Select solver';
            app.TextArea = uitextarea(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'Naive solution', 'Test solution', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'Naive solution';
            app.STARTButton = uibutton(app.GridLayout2, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SavesolutionButton = uibutton(app.GridLayout3, 'push');
            app.SavesolutionButton.Text = 'Save solution';
            app.ShowsolutionButton = uibutton(app.GridLayout3, 'push');
            app.ShowsolutionButton.ButtonPushedFcn = createCallbackFcn(app, @ShowsolutionButtonPushed, true);
            app.ShowsolutionButton.Text = 'Show solution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = solverWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end